classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Image2    matlab.ui.control.Image
        Image     matlab.ui.control.Image
    end

    
    properties (Access = public)
        %% Oggetti
        Objects
        WeightSensor
        
        %% Timer
        masterTimer;
        SpawnTimer;

        %% Modbus
        mb;
        plc_connected = false;

    end
    
    methods (Access = public)
        %% Master Loop
        function masterLoop(app)
            if app.plc_connected
                try
                    % Scorro su tutti gli oggetti dell'array objects
                    for i = 1:length(app.Objects)
                        obj = app.Objects{i};
                        app.    Update(obj);
                    end
                catch e
                    disp(['Errore lettura coil: ' e.message]);
                end
            end
        end

        %% Funzione per generare gli oggetti
         function SpawnObject(app)       
            % Tipo casuale
            value = rand;
            if value < 0.33
                type = "A";
                weight = 1 + rand;
                color = [1 0 0];
            elseif value < 0.66 && value > 0.33
                type = "B";
                weight = 4 + rand;
                color = [0 1 0];
            else 
                type = "C";
                weight = 7 + rand;
                color = [0 0 1];
            end
                                         
            % Creazione grafica
            objGraphic = uilabel(app.UIFigure);     
            objGraphic.Text = type;
            objGraphic.BackgroundColor = color;
            objGraphic.Position = [-20 400 40 40];
               
            % Creazione oggetto logico
            obj = IndustrialObject(type, weight, objGraphic);
               
            % Salvataggio nell'array
            app.Objects{end+1} = obj;
               
            disp("Nuovo oggetto creato:")
            disp(weight)
        
         end

        %% Aggiornamento di tutti gli object (forse conviene metterlo dentro la classe IndustrialObject?)
        function Update(app, obj)
            switch obj.State
                case {"Entered", "Discarded"}
                    obj.Move(10, 0)
                    if obj.GetX() < 301 && obj.GetX() > 290
                        app.writeReal(2049, obj.GetWeight());
                        obj.SetState("WaitingBalance");
                    end
                case "WaitingBalance"
                    valid = read(app.mb, "coils", 1, 1);
                    if valid
                        obj.SetState("Dropping");
                    else 
                        obj.SetState("Discarded")
                    end

                case "Dropping"
                    obj.Move(0, -10)
                case "Finished"
                    % nulla
            end
        end
        %% Utilities
        % Funzione per leggere un valore reale da openplc
        function value = readReal(app, address)
            regs = read(app.mb, "holdingregs", address, 2);        
            u32 = bitshift(uint32(regs(1)),16) + uint32(regs(2));        
            value = typecast(u32,'single');        
        end
        function writeReal(app, address, value)
        u32 = typecast(single(value), 'uint32');      
            regs = double([ ...
                bitshift(u32,-16), ...
                bitand(u32,65535)
            ]);      
            write(app.mb, "holdingregs", address, regs);      
        end
                
        %% Start Simulation
        function StartSimulation(app)
           if ~app.plc_connected
                try 
                    app.mb = modbus('tcpip', '127.0.0.1', 502);
                    app.plc_connected = true;
                catch e
                    disp(e.message)
                    app.plc_connected = false;
                end
           end
        end

        %% Stop simulation
        function StopSimulation(app)
            % Ferma il master timer
            if ~isempty(app.masterTimer)
                stop(app.masterTimer);
            end
            % Chiudi connessione modbus
            if app.plc_connected && ~isempty(app.mb)
                try
                    delete(app.mb)
                catch
                end
                app.plc_connected = false;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            StartSimulation(app);
            if isempty(app.masterTimer) || ~isvalid(app.masterTimer)
                app.masterTimer = timer( ...
                    'ExecutionMode','fixedSpacing', ...
                    'Period', 0.1, ...
                    'BusyMode','drop', ...
                    'TimerFcn',@(~,~) app.masterLoop());
            end

            app.Objects = {};
            app.SpawnTimer = timer;
            app.SpawnTimer.ExecutionMode = 'fixedRate';
            app.SpawnTimer.Period = 3;
            app.SpawnTimer.TimerFcn = @(~,~) SpawnObject(app);

            app.SpawnTimer.ErrorFcn = @(~,e) disp(e.Data);
            app.masterTimer.ErrorFcn = @(~,e) disp(e.Data);
            
            if strcmp(app.masterTimer.Running,'off')
                start(app.masterTimer);
                start(app.SpawnTimer);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'assets', 'sensore di peso.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end